classdef SZOnset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SZOnsetReview                   matlab.ui.Figure
        DisplayOrder                    matlab.ui.container.ButtonGroup
        RawOrderButton                  matlab.ui.control.RadioButton
        SortedOrderButton               matlab.ui.control.RadioButton
        ExportButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ChannelHighlightLabel           matlab.ui.control.Label
        HighPassEditFieldLabel          matlab.ui.control.Label
        HighPassEditField               matlab.ui.control.NumericEditField
        LowPassEditFieldLabel           matlab.ui.control.Label
        LowPassEditField                matlab.ui.control.NumericEditField
        OnlineFilterButton              matlab.ui.control.Button
        DropDownTimeWin                 matlab.ui.control.DropDown
        TimeWindowLabel                 matlab.ui.control.Label
        UnLabelasBadUnHideChannelButton  matlab.ui.control.Button
        AmplitudeScaleLabel             matlab.ui.control.Label
        IncreaseButton                  matlab.ui.control.Button
        ParametersLabel                 matlab.ui.control.Label
        vLabel                          matlab.ui.control.Label
        vEditField                      matlab.ui.control.NumericEditField
        ImportButton                    matlab.ui.control.Button
        CalcPlotButton                  matlab.ui.control.Button
        DropDownnChannel                matlab.ui.control.DropDown
        NumberofChannelsLabel           matlab.ui.control.Label
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        TimeScrollLabel                 matlab.ui.control.Label
        ChannelScrollLabel              matlab.ui.control.Label
        SeizureOnsetDetectionToolLabel  matlab.ui.control.Label
        ClearHighlightButton            matlab.ui.control.Button
        RestoreFilterViewButton         matlab.ui.control.Button
        DecreaseButton                  matlab.ui.control.Button
        MainAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
           
        
    end
    
    properties (Access = public)
        PreprocessedObj % The raw object after full preprocessing
        Sensitivity % the interspace between signals
        SEEGdataRaw
        
        RTSEEGdataFilt
        
        RTPlotLineH % real time line plot handle
        RTPlotHLLineH
        
        RTChanSelect        
              
        % Detected results
        Nd
        
        % Detection time compensation
        NdCompensation
        
        SortedIndex % sorting the onset time
        
        % Review parameters
        ChanScrollStep
        TimeScrollStep
        
        AmplitudeIncreaseScale
        AmplitudeDecreaseScale
        
        BadChanIndx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RTPlotHLLineH       = [];
            
            app.MainAxes.XTickLabel = [];
            app.MainAxes.XTick      = [];
            app.MainAxes.YTickLabel = [];
            app.MainAxes.YTick      = [];
            app.MainAxes.YDir       = 'reverse';
            
            app.SZOnsetReview.Name  = 'Seizure Onset Detection';
            
            % Default Parameters
            
            app.Sensitivity          = 500;
            
            app.vEditField.Value = 5;
                        
            app.Nd               = [];
            
            app.ChanScrollStep   = 5;
            app.TimeScrollStep   = 2;
            
            app.AmplitudeIncreaseScale = 1.3;
            app.AmplitudeDecreaseScale = 0.7;
            
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile('OnsetPreproc*.mat');
            OnsetV = load([path,file]);
            app.PreprocessedObj = OnsetV.Onset;
            % Plot the raw SEEG data in the axis
            cla(app.MainAxes)
            app.SEEGdataRaw = squeeze(app.PreprocessedObj.DFileUnfilt(:,:,1));
            
            app.RTSEEGdataFilt = app.SEEGdataRaw;
            
            for i = 1:length(app.PreprocessedObj.ChanIndx)
                LineH{i,:} = plot(app.MainAxes,app.SEEGdataRaw(i,:) + (i - 1) * app.Sensitivity,'k');
                hold(app.MainAxes,"on")
                axis(app.MainAxes,'tight')
                
                
            end
            % Define Y axis at first
            app.MainAxes.YLim = [-1,length(app.PreprocessedObj.ChanIndx)+1] * app.Sensitivity;
            
            
            app.RTPlotLineH = LineH;
            app.SZOnsetReview.Visible = 'off';
            app.SZOnsetReview.Visible = 'on';
            % Add the labels
            app.MainAxes.YTick = 0:app.Sensitivity:(length(app.PreprocessedObj.ChanIndx) - 1) * app.Sensitivity;
            app.MainAxes.YTickLabel = app.PreprocessedObj.DFileUnfilt.chanlabels;
            xticks(app.MainAxes,'auto');
            xticklabels(app.MainAxes,app.MainAxes.XTick./app.PreprocessedObj.DFile.fsample)
            
            app.MainAxes.XLabel.String = 'Time (seconds)';
            
            app.UITable.Data = app.PreprocessedObj.DFileUnfilt.chanlabels';
            
            % Initialize
            app.DropDownTimeWin.Value   = 0;
            app.DropDownnChannel.Value  = 0;
            app.HighPassEditField.Value = 0;
            app.LowPassEditField.Value  = 0;
            app.RawOrderButton.Value    = true;
            app.Nd                      = [];
            app.RTChanSelect            = [];
            
            app.NdCompensation = app.PreprocessedObj.WinLength;
            
            app.BadChanIndx = ones(app.PreprocessedObj.DFile.nchannels,1);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            indices = indices(1);
            app.RTChanSelect = indices;
            if strcmp(app.DisplayOrder.SelectedObject.Text,'Sorted Order')
                tempSEEGdata = app.RTSEEGdataFilt(app.SortedIndex,:);                
            elseif strcmp(app.DisplayOrder.SelectedObject.Text,'Raw Order')
                tempSEEGdata = app.RTSEEGdataFilt;                
            end
            % Remove the previous line before plotting
            if isempty(app.RTPlotHLLineH)
                % Plot with thicker line length overlay
                app.RTPlotHLLineH = plot(app.MainAxes,tempSEEGdata(indices,:) + (indices - 1) * app.Sensitivity,...
                    'Color',[0.5 0.5 0.5 0.3],"LineWidth",6);
            elseif ~isempty(app.RTPlotHLLineH)
                % Remember to delete current overlay at next selection
                app.RTPlotHLLineH.delete;
                app.RTPlotHLLineH = plot(app.MainAxes,tempSEEGdata(indices,:) + (indices - 1) * app.Sensitivity,...
                    'Color',[0.5 0.5 0.5 0.3],"LineWidth",6);
            end


        end

        % Button pushed function: ClearHighlightButton
        function ClearHighlightButtonPushed(app, event)
            if ~isempty(app.RTPlotHLLineH)
                % Remember to delete current overlay at next selection
                app.RTPlotHLLineH.delete;                
            end
            app.RTChanSelect = [];
        end

        % Button pushed function: CalcPlotButton
        function CalcPlotButtonPushed(app, event)
            Ndtemp = [];
            v = app.vEditField.Value;
            
            fsample   = app.PreprocessedObj.DFile.fsample;
            nsample   = app.PreprocessedObj.DFile.nsamples;
            
            maxZscore = app.PreprocessedObj.maxZscore;
            ChanIndx  = app.PreprocessedObj.ChanIndx;
            StepLength = app.PreprocessedObj.StepLength;
            for i = 1:length(ChanIndx)
                [Ndtemp(i,:),~] = OnsetDetectionGlobalMin(maxZscore(i,:),v,StepLength);
                
            end
            % Add the compensation here
            Ndtemp = Ndtemp + app.NdCompensation;
            
            % Adjust Nd to make sure it's smaller than the full length
            for i = 1:length(Ndtemp)
                if round(Ndtemp(i) * fsample) > nsample
                    Ndtemp(i,:) = (nsample - 1)/fsample;                   
                end                   
            end
            
            app.Nd = Ndtemp;
            
            % Plot the results
            % Clear current axis
            cla(app.MainAxes)
            NdFinal = app.Nd;
            D = app.RTSEEGdataFilt;
            fsample = app.PreprocessedObj.DFile.fsample;
            nsamples = app.PreprocessedObj.DFile.nsamples;
            for i = 1:length(ChanIndx)
                LineH{i,1} = plot(app.MainAxes,1:round(NdFinal(i)*fsample),D(ChanIndx(i),...
                                  1:round(NdFinal(i)*fsample),1) + (i - 1) * app.Sensitivity,'k');
                hold(app.MainAxes,'on')
                LineH{i,2} = plot(app.MainAxes,round(NdFinal(i)*fsample) + 1:nsamples,D(ChanIndx(i),...
                                  round(NdFinal(i)*fsample) + 1:nsamples,1) + (i - 1) * app.Sensitivity,'r');
                axis(app.MainAxes,'tight')
            end
            app.RTPlotLineH = LineH;
            % Add the labels
            app.MainAxes.YTick      = 0:app.Sensitivity:(length(app.PreprocessedObj.ChanIndx) - 1) * app.Sensitivity;
            app.MainAxes.YTickLabel = app.PreprocessedObj.DFileUnfilt.chanlabels;
            xticks(app.MainAxes,'auto');
            xticklabels(app.MainAxes,app.MainAxes.XTick./app.PreprocessedObj.DFile.fsample)
            
            app.MainAxes.XLabel.String = 'Time (seconds)';
            
            % Reset win parameters
            app.DropDownTimeWin.Value  = 0;
            app.DropDownnChannel.Value = 0;

            
        end

        % Selection changed function: DisplayOrder
        function DisplayOrderSelectionChanged(app, event)
            selectedButton = app.DisplayOrder.SelectedObject;
            
            if isempty(app.Nd)
                return
                
            end
            
            cla(app.MainAxes)
            
            NdFinal   = app.Nd;
            ChanIndx  = app.PreprocessedObj.ChanIndx;
            D         = app.RTSEEGdataFilt;
            fsample   = app.PreprocessedObj.DFile.fsample;
            nsamples  = app.PreprocessedObj.DFile.nsamples;
            
            if strcmp(selectedButton.Text, 'Sorted Order') && ~isempty(app.Nd)
                [~,IndexSorted] = sort(NdFinal);
                for i = 1:length(IndexSorted)
                    tempIndx = IndexSorted(i);
                    LineH{i,1} = plot(app.MainAxes,1:round(NdFinal(tempIndx)*fsample),D(ChanIndx(tempIndx),...
                                      1:round(NdFinal(tempIndx)*fsample),1) + (i - 1) * app.Sensitivity,'k');
                    hold(app.MainAxes,'on')
                    LineH{i,2} = plot(app.MainAxes,round(NdFinal(tempIndx)*fsample) + 1:nsamples,...
                                      D(ChanIndx(tempIndx),round(NdFinal(tempIndx)*fsample) + 1:nsamples,1) + (i - 1) * app.Sensitivity,'r');
                    axis(app.MainAxes,'tight')
                end
                app.RTPlotLineH = LineH;
                app.SortedIndex = IndexSorted;
                % Add the labels
                app.MainAxes.YTick = 0:app.Sensitivity:(length(app.PreprocessedObj.ChanIndx) - 1) * app.Sensitivity;
                app.MainAxes.YTickLabel = app.PreprocessedObj.DFileUnfilt.chanlabels(ChanIndx(IndexSorted));
                xticks(app.MainAxes,'auto');
                xticklabels(app.MainAxes,app.MainAxes.XTick./app.PreprocessedObj.DFile.fsample)
                
                app.MainAxes.XLabel.String = 'Time (seconds)';
                
                % Remeber to change the channel labels
                app.UITable.Data = app.PreprocessedObj.DFileUnfilt.chanlabels(ChanIndx(IndexSorted))';
                
            elseif strcmp(selectedButton.Text, 'Raw Order') && ~isempty(app.Nd)
                % Redo the plot thing
                % Plot the results
                % Clear current axis
                for i = 1:length(ChanIndx)
                    LineH{i,1} = plot(app.MainAxes,1:round(NdFinal(i)*fsample),D(ChanIndx(i),...
                        1:round(NdFinal(i)*fsample),1) + (i - 1) * app.Sensitivity,'k');
                    hold(app.MainAxes,'on')
                    LineH{i,2} = plot(app.MainAxes,round(NdFinal(i)*fsample) + 1:nsamples,D(ChanIndx(i),...
                        round(NdFinal(i)*fsample) + 1:nsamples,1) + (i - 1) * app.Sensitivity,'r');
                    axis(app.MainAxes,'tight')
                end
                app.RTPlotLineH = LineH;
                % Add the labels
                app.MainAxes.YTick = 0:app.Sensitivity:(length(app.PreprocessedObj.ChanIndx) - 1) * app.Sensitivity;
                app.MainAxes.YTickLabel = app.PreprocessedObj.DFileUnfilt.chanlabels;
                xticks(app.MainAxes,'auto');
                xticklabels(app.MainAxes,app.MainAxes.XTick./app.PreprocessedObj.DFile.fsample)
                
                app.MainAxes.XLabel.String = 'Time (seconds)';
                
                % Remeber to change the channel labels
                app.UITable.Data = app.PreprocessedObj.DFileUnfilt.chanlabels';
            end
            
            % Reset win parameters
            app.DropDownTimeWin.Value  = 0;
            app.DropDownnChannel.Value = 0;
        end

        % Value changed function: DropDownnChannel
        function DropDownnChannelValueChanged(app, event)
            value = app.DropDownnChannel.Value;
            if value == 0
                app.MainAxes.YLim = [-1,length(app.PreprocessedObj.ChanIndx)+1] * app.Sensitivity;
            else
                app.MainAxes.YLim = [-1,value+1] * app.Sensitivity;
            end          
            
            
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            
            currentYlim = app.MainAxes.YLim;
            ChanStep    = app.ChanScrollStep;

            app.MainAxes.YLim = [currentYlim(1)/app.Sensitivity - ChanStep,currentYlim(2)/app.Sensitivity - ChanStep] * app.Sensitivity;

            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            
            currentYlim = app.MainAxes.YLim;
            ChanStep    = app.ChanScrollStep;
            
            app.MainAxes.YLim = [currentYlim(1)/app.Sensitivity + ChanStep,currentYlim(2)/app.Sensitivity + ChanStep] * app.Sensitivity;

        end

        % Value changed function: DropDownTimeWin
        function DropDownTimeWinValueChanged(app, event)
            value = app.DropDownTimeWin.Value;
            
            nsamples    = app.PreprocessedObj.DFile.nsamples;
            fsample   = app.PreprocessedObj.DFile.fsample;
            
            
            if value == 0
                app.MainAxes.XLim = [1,nsamples];
            else
                app.MainAxes.XLim = [1,value * fsample];                
            end


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            currentXlim = app.MainAxes.XLim;
            
            fsample     = app.PreprocessedObj.DFile.fsample;
            StepSample   = app.TimeScrollStep * fsample;
            
            app.MainAxes.XLim = [currentXlim(1) - StepSample,currentXlim(2) - StepSample];

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            currentXlim = app.MainAxes.XLim;
            
            fsample     = app.PreprocessedObj.DFile.fsample;
            StepSample   = app.TimeScrollStep * fsample;
            
            app.MainAxes.XLim = [currentXlim(1) + StepSample,currentXlim(2) + StepSample];
        end

        % Button pushed function: OnlineFilterButton
        function OnlineFilterButtonPushed(app, event)
            HighPassFreq = app.HighPassEditField.Value;
            LowPassFreq  = app.LowPassEditField.Value;
            
            tempSEEGdata = app.SEEGdataRaw;
            fsample      = app.PreprocessedObj.DFile.fsample;
            
            % The online filtering is much faster for IIR
            if HighPassFreq == 0 && LowPassFreq == 0
                tempSEEGdata = app.SEEGdataRaw;
            elseif HighPassFreq ~= 0 && LowPassFreq == 0 % High Pass
                tempSEEGdata = highpass(tempSEEGdata',HighPassFreq,fsample,'ImpulseResponse','iir')';
                
            elseif HighPassFreq == 0 && LowPassFreq ~= 0  % Low Pass
                tempSEEGdata = lowpass(tempSEEGdata',LowPassFreq,fsample,'ImpulseResponse','iir')';
                
            elseif HighPassFreq ~= 0 && LowPassFreq ~= 0 % Band Pass
                tempSEEGdata = bandpass(tempSEEGdata',[HighPassFreq, LowPassFreq],fsample,'ImpulseResponse','iir')';
                
            end
            
            % Replace the current filtered data
            app.RTSEEGdataFilt = tempSEEGdata;
            
            % Replace the plot lines
            if size(app.RTPlotLineH,2) == 1 % single color line
                for i = 1:length(app.RTPlotLineH)
                    app.RTPlotLineH{i}.YData = tempSEEGdata(i,:) + (i - 1) * app.Sensitivity;
                end
            elseif size(app.RTPlotLineH,2) == 2 % dual color line
                
                % Sorted
                if strcmp(app.DisplayOrder.SelectedObject.Text,'Raw Order')
                    for i = 1:length(app.RTPlotLineH)
                        app.RTPlotLineH{i,1}.YData = tempSEEGdata(i,1:length(app.RTPlotLineH{i,1}.YData)) + (i - 1) * app.Sensitivity;
                        app.RTPlotLineH{i,2}.YData = tempSEEGdata(i,length(app.RTPlotLineH{i,1}.YData) + 1:end) + (i - 1) * app.Sensitivity;
                    end
                elseif strcmp(app.DisplayOrder.SelectedObject.Text,'Sorted Order') % Unsorted
                    tempSEEGdata = tempSEEGdata(app.SortedIndex,:);
                    for i = 1:length(app.RTPlotLineH)
                        app.RTPlotLineH{i,1}.YData = tempSEEGdata(i,1:length(app.RTPlotLineH{i,1}.YData)) + (i - 1) * app.Sensitivity;
                        app.RTPlotLineH{i,2}.YData = tempSEEGdata(i,length(app.RTPlotLineH{i,1}.YData) + 1:end) + (i - 1) * app.Sensitivity;
                    end                   
                end
                
            end
            
        end

        % Button pushed function: IncreaseButton
        function IncreaseButtonPushed(app, event)
   
            tempSEEGdata = app.RTSEEGdataFilt;
            tempSEEGdata = tempSEEGdata .* app.AmplitudeIncreaseScale;
            
            app.RTSEEGdataFilt = tempSEEGdata;
                        
            if size(app.RTPlotLineH,2) == 1 % single color line
                for i = 1:length(app.RTPlotLineH)
                    app.RTPlotLineH{i}.YData = tempSEEGdata(i,:)+ (i - 1) * app.Sensitivity;
                end
            elseif size(app.RTPlotLineH,2) == 2 % dual color line
                
                % Sorted
                if strcmp(app.DisplayOrder.SelectedObject.Text,'Raw Order')
                    for i = 1:length(app.RTPlotLineH)
                        app.RTPlotLineH{i,1}.YData = tempSEEGdata(i,1:length(app.RTPlotLineH{i,1}.YData)) + (i - 1) * app.Sensitivity;
                        app.RTPlotLineH{i,2}.YData = tempSEEGdata(i,length(app.RTPlotLineH{i,1}.YData) + 1:end) + (i - 1) * app.Sensitivity;
                    end
                elseif strcmp(app.DisplayOrder.SelectedObject.Text,'Sorted Order') % Unsorted
                    tempSEEGdata = tempSEEGdata(app.SortedIndex,:);
                    for i = 1:length(app.RTPlotLineH)
                        app.RTPlotLineH{i,1}.YData = tempSEEGdata(i,1:length(app.RTPlotLineH{i,1}.YData)) + (i - 1) * app.Sensitivity;
                        app.RTPlotLineH{i,2}.YData = tempSEEGdata(i,length(app.RTPlotLineH{i,1}.YData) + 1:end) + (i - 1) * app.Sensitivity;
                    end
                end
                
            end
        end

        % Button pushed function: RestoreFilterViewButton
        function RestoreFilterViewButtonPushed(app, event)
            app.HighPassEditField.Value = 0;
            app.LowPassEditField.Value  = 0;
            OnlineFilterButtonPushed(app, event)
            
        end

        % Button pushed function: DecreaseButton
        function DecreaseButtonPushed(app, event)
               
            tempSEEGdata = app.RTSEEGdataFilt;
            tempSEEGdata = tempSEEGdata .* app.AmplitudeDecreaseScale;
            
            app.RTSEEGdataFilt = tempSEEGdata;
                        
            if size(app.RTPlotLineH,2) == 1 % single color line
                for i = 1:length(app.RTPlotLineH)
                    app.RTPlotLineH{i}.YData = tempSEEGdata(i,:)+ (i - 1) * app.Sensitivity;
                end
            elseif size(app.RTPlotLineH,2) == 2 % dual color line
                
                % Sorted
                if strcmp(app.DisplayOrder.SelectedObject.Text,'Raw Order')
                    for i = 1:length(app.RTPlotLineH)
                        app.RTPlotLineH{i,1}.YData = tempSEEGdata(i,1:length(app.RTPlotLineH{i,1}.YData)) + (i - 1) * app.Sensitivity;
                        app.RTPlotLineH{i,2}.YData = tempSEEGdata(i,length(app.RTPlotLineH{i,1}.YData) + 1:end) + (i - 1) * app.Sensitivity;
                    end
                elseif strcmp(app.DisplayOrder.SelectedObject.Text,'Sorted Order') % Unsorted
                    tempSEEGdata = tempSEEGdata(app.SortedIndex,:);
                    for i = 1:length(app.RTPlotLineH)
                        app.RTPlotLineH{i,1}.YData = tempSEEGdata(i,1:length(app.RTPlotLineH{i,1}.YData)) + (i - 1) * app.Sensitivity;
                        app.RTPlotLineH{i,2}.YData = tempSEEGdata(i,length(app.RTPlotLineH{i,1}.YData) + 1:end) + (i - 1) * app.Sensitivity;
                    end
                end
                
            end
        end

        % Button pushed function: UnLabelasBadUnHideChannelButton
        function UnLabelasBadUnHideChannelButtonPushed(app, event)
            if isempty(app.RTChanSelect)
                return
            end
            i = app.RTChanSelect;
            if app.RTPlotLineH{i,1}.Color == [1 1 1]
                app.RTPlotLineH{i,1}.Color = "k";
                app.RTPlotLineH{i,2}.Color = "r";
                % Record the raw index for exporting
                if strcmp(app.DisplayOrder.SelectedObject.Text,'Raw Order')
                    app.BadChanIndx(i) = 1;
                elseif strcmp(app.DisplayOrder.SelectedObject.Text,'Sorted Order') % Unsorted
                    app.BadChanIndx(app.SortedIndex(i)) = 1;
                end
                
            elseif app.RTPlotLineH{i,1}.Color == [0 0 0]
                app.RTPlotLineH{i,1}.Color = "w";
                app.RTPlotLineH{i,2}.Color = "w";
                                % Record the raw index for exporting
                if strcmp(app.DisplayOrder.SelectedObject.Text,'Raw Order')
                    app.BadChanIndx(i) = 0;
                elseif strcmp(app.DisplayOrder.SelectedObject.Text,'Sorted Order') % Unsorted
                    app.BadChanIndx(app.SortedIndex(i)) = 0;
                end            
                
            end
            

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Prepare the data
            ChannelNames = app.PreprocessedObj.DFile.chanlabels';
            Onset = app.Nd;
            Good1Bad0 = app.BadChanIndx;
            % Write the csv file of channel label, Nd and 
            T = table(ChannelNames,Onset,Good1Bad0);
            writetable(T,'OnsetResults.csv','Delimiter',',')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SZOnsetReview = uifigure('Visible', 'off');
            app.SZOnsetReview.Name = 'MATLAB App';
            app.DisplayOrder = uibuttongroup(app.SZOnsetReview);
            app.DisplayOrder.SelectionChangedFcn = createCallbackFcn(app, @DisplayOrderSelectionChanged, true);
            app.DisplayOrder.Title = 'Display Order';
            app.RawOrderButton = uiradiobutton(app.DisplayOrder);
            app.RawOrderButton.Text = 'Raw Order';
            app.RawOrderButton.Value = true;
            app.SortedOrderButton = uiradiobutton(app.DisplayOrder);
            app.SortedOrderButton.Text = 'Sorted Order';
            app.ExportButton = uibutton(app.SZOnsetReview, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UITable = uitable(app.SZOnsetReview);
            app.UITable.ColumnName = {'Channels'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ChannelHighlightLabel = uilabel(app.SZOnsetReview);
            app.ChannelHighlightLabel.Text = 'Channel Highlight';
            app.HighPassEditFieldLabel = uilabel(app.SZOnsetReview);
            app.HighPassEditFieldLabel.Text = 'High Pass';
            app.HighPassEditField = uieditfield(app.SZOnsetReview, 'numeric');
            app.LowPassEditFieldLabel = uilabel(app.SZOnsetReview);
            app.LowPassEditFieldLabel.Text = 'Low Pass';
            app.LowPassEditField = uieditfield(app.SZOnsetReview, 'numeric');
            app.OnlineFilterButton = uibutton(app.SZOnsetReview, 'push');
            app.OnlineFilterButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineFilterButtonPushed, true);
            app.OnlineFilterButton.Text = 'Online Filter';
            app.DropDownTimeWin = uidropdown(app.SZOnsetReview);
            app.DropDownTimeWin.Items = {'5s', '15s', '30s', '60s', 'Full'};
            app.DropDownTimeWin.ItemsData = [5 15 30 60 0];
            app.DropDownTimeWin.ValueChangedFcn = createCallbackFcn(app, @DropDownTimeWinValueChanged, true);
            app.DropDownTimeWin.Value = 5;
            app.TimeWindowLabel = uilabel(app.SZOnsetReview);
            app.TimeWindowLabel.Text = 'Time Window';
            app.UnLabelasBadUnHideChannelButton = uibutton(app.SZOnsetReview, 'push');
            app.UnLabelasBadUnHideChannelButton.ButtonPushedFcn = createCallbackFcn(app, @UnLabelasBadUnHideChannelButtonPushed, true);
            app.UnLabelasBadUnHideChannelButton.Text = {'(Un)Label as Bad'; '(Un)Hide Channel'};
            app.AmplitudeScaleLabel = uilabel(app.SZOnsetReview);
            app.AmplitudeScaleLabel.Text = 'Amplitude Scale';
            app.IncreaseButton = uibutton(app.SZOnsetReview, 'push');
            app.IncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButtonPushed, true);
            app.IncreaseButton.Text = 'Increase';
            app.ParametersLabel = uilabel(app.SZOnsetReview);
            app.ParametersLabel.Text = 'Parameters';
            app.vLabel = uilabel(app.SZOnsetReview);
            app.vLabel.Text = 'v : ';
            app.vEditField = uieditfield(app.SZOnsetReview, 'numeric');
            app.ImportButton = uibutton(app.SZOnsetReview, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CalcPlotButton = uibutton(app.SZOnsetReview, 'push');
            app.CalcPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalcPlotButtonPushed, true);
            app.CalcPlotButton.Text = 'Calc & Plot';
            app.DropDownnChannel = uidropdown(app.SZOnsetReview);
            app.DropDownnChannel.Items = {'10', '25', '50', 'Full'};
            app.DropDownnChannel.ItemsData = [10 25 50 0];
            app.DropDownnChannel.ValueChangedFcn = createCallbackFcn(app, @DropDownnChannelValueChanged, true);
            app.DropDownnChannel.Value = 10;
            app.NumberofChannelsLabel = uilabel(app.SZOnsetReview);
            app.NumberofChannelsLabel.Text = 'Number of Channels';
            app.PreviousButton = uibutton(app.SZOnsetReview, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '<= Previous';
            app.NextButton = uibutton(app.SZOnsetReview, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next =>';
            app.UpButton = uibutton(app.SZOnsetReview, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.SZOnsetReview, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.TimeScrollLabel = uilabel(app.SZOnsetReview);
            app.TimeScrollLabel.Text = 'Time Scroll';
            app.ChannelScrollLabel = uilabel(app.SZOnsetReview);
            app.ChannelScrollLabel.Text = 'Channel Scroll';
            app.SeizureOnsetDetectionToolLabel = uilabel(app.SZOnsetReview);
            app.SeizureOnsetDetectionToolLabel.Text = 'Seizure Onset Detection Tool';
            app.ClearHighlightButton = uibutton(app.SZOnsetReview, 'push');
            app.ClearHighlightButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHighlightButtonPushed, true);
            app.ClearHighlightButton.Text = 'Clear Highlight';
            app.RestoreFilterViewButton = uibutton(app.SZOnsetReview, 'push');
            app.RestoreFilterViewButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreFilterViewButtonPushed, true);
            app.RestoreFilterViewButton.Text = 'Restore Filter & View';
            app.DecreaseButton = uibutton(app.SZOnsetReview, 'push');
            app.DecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseButtonPushed, true);
            app.DecreaseButton.Text = 'Decrease';
            app.MainAxes = uiaxes(app.SZOnsetReview);
            zlabel(app.MainAxes, 'Z')
            % Show the figure after all components are created
            app.SZOnsetReview.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SZOnset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SZOnsetReview)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SZOnsetReview)
        end
    end
end